classdef WordleSolver < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        UITable                  matlab.ui.control.Table
        LetterInfoDropDown       matlab.ui.control.DropDown
        LetterInfoDropDownLabel  matlab.ui.control.Label
        NEXTButton               matlab.ui.control.Button
        Panel                    matlab.ui.container.Panel
        GridLayout               matlab.ui.container.GridLayout
        L30Label                 matlab.ui.control.Label
        L29Label                 matlab.ui.control.Label
        L28Label                 matlab.ui.control.Label
        L27Label                 matlab.ui.control.Label
        L26Label                 matlab.ui.control.Label
        L25Label                 matlab.ui.control.Label
        L24Label                 matlab.ui.control.Label
        L23Label                 matlab.ui.control.Label
        L22Label                 matlab.ui.control.Label
        L21Label                 matlab.ui.control.Label
        L20Label                 matlab.ui.control.Label
        L19Label                 matlab.ui.control.Label
        L18Label                 matlab.ui.control.Label
        L17Label                 matlab.ui.control.Label
        L16Label                 matlab.ui.control.Label
        L15Label                 matlab.ui.control.Label
        L14Label                 matlab.ui.control.Label
        L13Label                 matlab.ui.control.Label
        L12Label                 matlab.ui.control.Label
        L11Label                 matlab.ui.control.Label
        L10Label                 matlab.ui.control.Label
        L9Label                  matlab.ui.control.Label
        L8Label                  matlab.ui.control.Label
        L7Label                  matlab.ui.control.Label
        L6Label                  matlab.ui.control.Label
        L5Label                  matlab.ui.control.Label
        L4Label                  matlab.ui.control.Label
        L3Label                  matlab.ui.control.Label
        L2Label                  matlab.ui.control.Label
        L1Label                  matlab.ui.control.Label
        RESETButton              matlab.ui.control.Button
        WordleAssiterLabel       matlab.ui.control.Label
        PREVButton               matlab.ui.control.Button
        ZButton                  matlab.ui.control.Button
        YButton                  matlab.ui.control.Button
        XButton                  matlab.ui.control.Button
        WButton                  matlab.ui.control.Button
        VButton                  matlab.ui.control.Button
        UButton                  matlab.ui.control.Button
        TButton                  matlab.ui.control.Button
        SButton                  matlab.ui.control.Button
        RButton                  matlab.ui.control.Button
        QButton                  matlab.ui.control.Button
        PButton                  matlab.ui.control.Button
        OButton                  matlab.ui.control.Button
        NButton                  matlab.ui.control.Button
        MButton                  matlab.ui.control.Button
        LButton                  matlab.ui.control.Button
        KButton                  matlab.ui.control.Button
        JButton                  matlab.ui.control.Button
        IButton                  matlab.ui.control.Button
        HButton                  matlab.ui.control.Button
        GButton                  matlab.ui.control.Button
        FButton                  matlab.ui.control.Button
        EButton                  matlab.ui.control.Button
        DButton                  matlab.ui.control.Button
        CButton                  matlab.ui.control.Button
        BButton                  matlab.ui.control.Button
        AButton                  matlab.ui.control.Button
    end

    % Private properties
    properties (Access = private)
        currentRow    (1, 1)    int16;  % Current row of word
        currentColumn (1, 1)    int16;  % Current column of word
        dataCell      (1, :)    cell;   % Words
        colorMap      (1, 39)   string; % Map of states of the grid
        allowedWords  (:, :, :) cell;   % All allowed words to a box
        alpha         (1, 26)   string; % Alpha numeric list
    end

    methods (Access = private)

        % Increment the index but bound
        function [] = IncrementIndex(app, curIdx)
            % Increment index if available or not out of bounds
            if curIdx < 30
                app.currentColumn = app.currentColumn + 1;
                % Check if out of word
                if app.currentColumn >= 6
                    app.currentRow    = app.currentRow + 1;
                    app.currentColumn = 1;
                end
            end
        end

        % Decrement the index the index but bound
        function [] = DecrementIndex(app, curIdx)
            % Decrement index if available or not out of bounds
            if curIdx > 1
                app.currentColumn = app.currentColumn - 1;
                % Check if out of word
                if app.currentColumn < 1
                    app.currentRow    = app.currentRow - 1;
                    app.currentColumn = 5;
                end
            end
        end

        % Highlight the current box
        function [] = OutlineBox(app, curIdx)
            % Generate the appropriate string
            letter                  = "L" + num2str(curIdx) + "Label";
            app.(letter).FontColor  = "green";
            app.(letter).FontSize   = 20;
            app.(letter).FontWeight = "bold";
        end

        % Unhighlight the current box
        function [] = UnOutlineBox(app, curIdx)
            % Generate the appropriate string
            letter                  = "L" + num2str(curIdx) + "Label";
            app.(letter).FontColor  = "black";
            app.(letter).FontSize   = 20;
            app.(letter).FontWeight = "bold";
        end

        % Highlight the backrounds color based on the letter state
        function [] = HighlightLetter(app, colorIn, curIdx)
            % Generate the appropriate string
            letter                       = "L" + num2str(curIdx) + "Label";
            app.(letter).BackgroundColor = colorIn;
        end

        % Update the current box based on the color rule
        function [] = ComputeReducedSet(app, curIdx)
            % Get the current box
            letter = "L" + num2str(curIdx) + "Label";
            % Get the current letter
            boxLetter = app.(letter).Text;
            % Get the current color
            boxColor = app.colorMap(curIdx);

            % Check if the current box letter is a letter
            % If not a letter, add all possibiltiies and 
            % exit processing
            if ~all(isletter(boxLetter))
                app.allowedWords{app.currentRow, app.currentColumn}...
                    = app.dataCell;
                return;
            end

            % Intiialize cell to hold the data
            tempData = {};

            % Iterate over all the words
            for kk = 1 : length(app.dataCell)
                % Get the current word and letter
                curWord = app.dataCell{kk};
                curLet  = curWord(app.currentColumn);

                % Switch case based on color
                switch (boxColor)
                    % No rule applied
                    case "N/A"
                        tempData{end + 1} = curWord;
                    % Equality rule
                    case "Green"
                        if upper(curLet) == upper(boxLetter)
                            tempData{end + 1} = curWord;
                        end
                    % Not in any words
                    case "Gray"
                        if ~contains(upper(curWord), boxLetter)
                            tempData{end + 1} = curWord;
                        end
                    % Letter in word; just not at the index
                    case "Yellow"
                        if upper(curLet) ~= upper(boxLetter) && ...
                                contains(upper(curWord), boxLetter)
                            tempData{end + 1} = curWord;
                        end
                    % Unknown
                    otherwise
                        tempData{end + 1} = curWord;
                end
            end

            % Update the entry for this box
            app.allowedWords{app.currentRow, app.currentColumn} ...
                = tempData;
        end

        function [] = UpdateProbabilityForLetter(app)
            
            % Initialize the probs
            letterProbability = dictionary(app.alpha, zeros(1, 26));

            % Initilaize a reduced set
            reduSet = app.dataCell;

            % Iterate over all the boxes and compute the set intersection
            for ii = 1 : 6
                for jj = 1 : 5
                    % Get the data set in the box
                    boxData = app.allowedWords{ii, jj};
                    % Compute the "and" join between both datasets
                    reduSet = boxData(contains(boxData, reduSet));
                end
            end

            % Iterate over the entire alphabet
            for aa = 1 : length(app.alpha)
                % Get our test letter
                testLetter = app.alpha(aa);
                % Iterate over all joined box data
                for kk = 1 : length(reduSet)
                    % Get the current word and test letter
                    curWord = reduSet{kk};
                    curLet  = curWord(app.currentColumn);
                    % Test the letter
                    if upper(testLetter) == upper(curLet)
                        letterProbability(testLetter) = ...
                            letterProbability(testLetter) + 1;
                    end
                end
            end

            % Get the values
            vals  = letterProbability.values;
            probs = vals / sum(vals);

            % Update the key-val pairs
            letterProbability(letterProbability.keys) = 100 * probs;

            % Convert to table and save to prob table
            app.UITable.Data = entries(letterProbability);
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Add path to data
            addpath("Data\");

            % Import data
            dataTable    = readtable("words.txt");
            app.dataCell = table2cell(dataTable);

            % Initialize the current posiion
            app.currentColumn = 1;
            app.currentRow    = 1;

            % Outline current position
            app.OutlineBox(1);

            % Initialize alphebet and probabiltiies
            app.alpha = {"A", "B", "C", "D", "E", "F", "G", "H" ...
                         "I", "J", "K", "L", "M", "N", "O", "P", ...
                         "Q", "R", "S", "T", "U", "V", "W", "X", ...
                         "Y", "Z"};
            prob = zeros(1, 26);

            % Initialize the table
            app.UITable.Data = table(app.alpha.', prob.');

            % Initialize the color map
            app.colorMap(1, 30) = "N/A";

            % Initialize the allowed words for each box
            app.allowedWords = cell.empty(6, 5, 0);
            for ii = 1 : 6
                for jj = 1 : 5
                    app.allowedWords{ii, jj, 1} = app.dataCell;
                end
            end

            % Update the probability for the current index
            app.UpdateProbabilityForLetter();
        end

        % Button pushed function: AButton
        function AButtonPushed(app, event)
            % Compute the current index (letter) to populate
            curIdx = (app.currentRow - 1) * 5 + ...
                app.currentColumn;

            % Generate the appropriate string
            letter       = "L" + num2str(curIdx) + "Label";
            app.(letter).Text = "A";
        end

        % Button pushed function: BButton
        function BButtonPushed(app, event)
            % Compute the current index (letter) to populate
            curIdx = (app.currentRow - 1) * 5 + ...
                app.currentColumn;

            % Generate the appropriate string
            letter       = "L" + num2str(curIdx) + "Label";
            app.(letter).Text = "B";
        end

        % Button pushed function: CButton
        function CButtonPushed(app, event)
            % Compute the current index (letter) to populate
            curIdx = (app.currentRow - 1) * 5 + ...
                app.currentColumn;

            % Generate the appropriate string
            letter       = "L" + num2str(curIdx) + "Label";
            app.(letter).Text = "C";
        end

        % Button pushed function: DButton
        function DButtonPushed(app, event)
            % Compute the current index (letter) to populate
            curIdx = (app.currentRow - 1) * 5 + ...
                app.currentColumn;

            % Generate the appropriate string
            letter       = "L" + num2str(curIdx) + "Label";
            app.(letter).Text = "D";
        end

        % Button pushed function: EButton
        function EButtonPushed(app, event)
            % Compute the current index (letter) to populate
            curIdx = (app.currentRow - 1) * 5 + ...
                app.currentColumn;

            % Generate the appropriate string
            letter       = "L" + num2str(curIdx) + "Label";
            app.(letter).Text = "E";
        end

        % Button pushed function: FButton
        function FButtonPushed(app, event)
            % Compute the current index (letter) to populate
            curIdx = (app.currentRow - 1) * 5 + ...
                app.currentColumn;

            % Generate the appropriate string
            letter       = "L" + num2str(curIdx) + "Label";
            app.(letter).Text = "F";
        end

        % Button pushed function: GButton
        function GButtonPushed(app, event)
            % Compute the current index (letter) to populate
            curIdx = (app.currentRow - 1) * 5 + ...
                app.currentColumn;

            % Generate the appropriate string
            letter       = "L" + num2str(curIdx) + "Label";
            app.(letter).Text = "G";
        end

        % Button pushed function: HButton
        function HButtonPushed(app, event)
            % Compute the current index (letter) to populate
            curIdx = (app.currentRow - 1) * 5 + ...
                app.currentColumn;

            % Generate the appropriate string
            letter       = "L" + num2str(curIdx) + "Label";
            app.(letter).Text = "H";
        end

        % Button pushed function: IButton
        function IButtonPushed(app, event)
            % Compute the current index (letter) to populate
            curIdx = (app.currentRow - 1) * 5 + ...
                app.currentColumn;

            % Generate the appropriate string
            letter       = "L" + num2str(curIdx) + "Label";
            app.(letter).Text = "I";
        end

        % Button pushed function: JButton
        function JButtonPushed(app, event)
            % Compute the current index (letter) to populate
            curIdx = (app.currentRow - 1) * 5 + ...
                app.currentColumn;

            % Generate the appropriate string
            letter       = "L" + num2str(curIdx) + "Label";
            app.(letter).Text = "J";
        end

        % Button pushed function: KButton
        function KButtonPushed(app, event)
            % Compute the current index (letter) to populate
            curIdx = (app.currentRow - 1) * 5 + ...
                app.currentColumn;

            % Generate the appropriate string
            letter       = "L" + num2str(curIdx) + "Label";
            app.(letter).Text = "K";
        end

        % Button pushed function: LButton
        function LButtonPushed(app, event)
            % Compute the current index (letter) to populate
            curIdx = (app.currentRow - 1) * 5 + ...
                app.currentColumn;

            % Generate the appropriate string
            letter       = "L" + num2str(curIdx) + "Label";
            app.(letter).Text = "L";
        end

        % Button pushed function: MButton
        function MButtonPushed(app, event)
            % Compute the current index (letter) to populate
            curIdx = (app.currentRow - 1) * 5 + ...
                app.currentColumn;

            % Generate the appropriate string
            letter       = "L" + num2str(curIdx) + "Label";
            app.(letter).Text = "M";
        end

        % Button pushed function: NButton
        function NButtonPushed(app, event)
            % Compute the current index (letter) to populate
            curIdx = (app.currentRow - 1) * 5 + ...
                app.currentColumn;

            % Generate the appropriate string
            letter       = "L" + num2str(curIdx) + "Label";
            app.(letter).Text = "N";
        end

        % Button pushed function: OButton
        function OButtonPushed(app, event)
            % Compute the current index (letter) to populate
            curIdx = (app.currentRow - 1) * 5 + ...
                app.currentColumn;

            % Generate the appropriate string
            letter       = "L" + num2str(curIdx) + "Label";
            app.(letter).Text = "O";
        end

        % Button pushed function: PButton
        function PButtonPushed(app, event)
            % Compute the current index (letter) to populate
            curIdx = (app.currentRow - 1) * 5 + ...
                app.currentColumn;

            % Generate the appropriate string
            letter       = "L" + num2str(curIdx) + "Label";
            app.(letter).Text = "P";
        end

        % Button pushed function: QButton
        function QButtonPushed(app, event)
            % Compute the current index (letter) to populate
            curIdx = (app.currentRow - 1) * 5 + ...
                app.currentColumn;

            % Generate the appropriate string
            letter       = "L" + num2str(curIdx) + "Label";
            app.(letter).Text = "Q";
        end

        % Button pushed function: RButton
        function RButtonPushed(app, event)
            % Compute the current index (letter) to populate
            curIdx = (app.currentRow - 1) * 5 + ...
                app.currentColumn;

            % Generate the appropriate string
            letter       = "L" + num2str(curIdx) + "Label";
            app.(letter).Text = "R";
        end

        % Button pushed function: SButton
        function SButtonPushed(app, event)
            % Compute the current index (letter) to populate
            curIdx = (app.currentRow - 1) * 5 + ...
                app.currentColumn;

            % Generate the appropriate string
            letter       = "L" + num2str(curIdx) + "Label";
            app.(letter).Text = "S";
        end

        % Button pushed function: TButton
        function TButtonPushed(app, event)
            % Compute the current index (letter) to populate
            curIdx = (app.currentRow - 1) * 5 + ...
                app.currentColumn;

            % Generate the appropriate string
            letter       = "L" + num2str(curIdx) + "Label";
            app.(letter).Text = "T";
        end

        % Button pushed function: UButton
        function UButtonPushed(app, event)
            % Compute the current index (letter) to populate
            curIdx = (app.currentRow - 1) * 5 + ...
                app.currentColumn;

            % Generate the appropriate string
            letter       = "L" + num2str(curIdx) + "Label";
            app.(letter).Text = "U";
        end

        % Button pushed function: VButton
        function VButtonPushed(app, event)
            % Compute the current index (letter) to populate
            curIdx = (app.currentRow - 1) * 5 + ...
                app.currentColumn;

            % Generate the appropriate string
            letter       = "L" + num2str(curIdx) + "Label";
            app.(letter).Text = "V";
        end

        % Button pushed function: WButton
        function WButtonPushed(app, event)
            % Compute the current index (letter) to populate
            curIdx = (app.currentRow - 1) * 5 + ...
                app.currentColumn;

            % Generate the appropriate string
            letter       = "L" + num2str(curIdx) + "Label";
            app.(letter).Text = "W";
        end

        % Button pushed function: XButton
        function XButtonPushed(app, event)
            % Compute the current index (letter) to populate
            curIdx = (app.currentRow - 1) * 5 + ...
                app.currentColumn;

            % Generate the appropriate string
            letter       = "L" + num2str(curIdx) + "Label";
            app.(letter).Text = "X";
        end

        % Button pushed function: YButton
        function YButtonPushed(app, event)
            % Compute the current index (letter) to populate
            curIdx = (app.currentRow - 1) * 5 + ...
                app.currentColumn;

            % Generate the appropriate string
            letter       = "L" + num2str(curIdx) + "Label";
            app.(letter).Text = "Y";
        end

        % Button pushed function: ZButton
        function ZButtonPushed(app, event)
            % Compute the current index (letter) to populate
            curIdx = (app.currentRow - 1) * 5 + ...
                app.currentColumn;

            % Generate the appropriate string
            letter       = "L" + num2str(curIdx) + "Label";
            app.(letter).Text = "Z";
        end

        % Button pushed function: PREVButton
        function PREVButtonPushed(app, event)
            % Get the current index
            curIdx = (app.currentRow - 1) * 5 + app.currentColumn;

            % Remove the current highlighting
            app.UnOutlineBox(curIdx)

            % Decrement
            app.DecrementIndex(curIdx);

            % Recompute the new index
            curIdx = (app.currentRow - 1) * 5 + app.currentColumn;

            % Outline current position
            app.OutlineBox(curIdx);

            % Update probs
            app.UpdateProbabilityForLetter()
        end

        % Button pushed function: RESETButton
        function RESETButtonPushed(app, event)

            % Iterate from the current Idx to 1 and reset all parmeters
            for ii = 30 : -1 : 1
                % Generate the appropriate string
                letter            = "L" + num2str(ii) + "Label";
                app.(letter).Text = "L" + num2str(ii);

                % Remove highlighting
                value = [0.13, 0.13, 0.13];
                app.HighlightLetter(value, ii);

                % Remove the current highlighting
                app.UnOutlineBox(ii)

                % Generate the appropriate string
                letter            = "L" + num2str(ii) + "Label";
                app.(letter).Text = "L" + num2str(ii);

                % Remove highlighting
                value = [0.13, 0.13, 0.13];
                app.HighlightLetter(value, ii);
            end

            % Initialize probs
            prob = zeros(1, 26);

            % Initialize the table
            app.UITable.Data = table(app.alpha.', prob.');

            % Initialize the color map
            app.colorMap(1, 30) = "N/A";

            % Initialize the allowed words for each box
            app.allowedWords = cell.empty(6, 5, 0);
            for ii = 1 : 6
                for jj = 1 : 5
                    app.allowedWords{ii, jj, 1} = app.dataCell;
                end
            end

            % Reset current position
            app.currentColumn = 1;
            app.currentRow    = 1;

            % Outline current position
            app.OutlineBox(1);

            % Recompute probs
            app.UpdateProbabilityForLetter();
        end

        % Button pushed function: NEXTButton
        function NEXTButtonPushed(app, event)
            % Get the current index
            curIdx = (app.currentRow - 1) * 5 + app.currentColumn;

            % Remove the current highlighting
            app.UnOutlineBox(curIdx)

            % Decrement
            app.IncrementIndex(curIdx);

            % Recompute the new index
            curIdx = (app.currentRow - 1) * 5 + app.currentColumn;

            % Outline current position
            app.OutlineBox(curIdx);

            % Update probs
            app.UpdateProbabilityForLetter()
        end

        % Value changed function: LetterInfoDropDown
        function LetterInfoDropDownValueChanged(app, event)
            % Get the current index
            curIdx = (app.currentRow - 1) * 5 + app.currentColumn;
            % Get the current string upon event
            value = app.LetterInfoDropDown.Value;

            % Update the color map
            app.colorMap(curIdx) = value;

            % Switch the string
            switch (value)

                % Case NA
                case "N/A"
                    % Update hightlight
                    value = [0.13, 0.13, 0.13];
                    app.HighlightLetter(value, curIdx)
                case "Gray"
                    % Update hightlight
                    value = 0.7 * ones(1, 3);
                    app.HighlightLetter(value, curIdx)
                case "Yellow"
                    % Update hightlight
                    app.HighlightLetter(value, curIdx)
                case "Green"
                    % Update hightlight
                    app.HighlightLetter(value, curIdx)
            end

            % Update the set for this box given color change
            app.ComputeReducedSet(curIdx);

            % Update probs
            app.UpdateProbabilityForLetter()
        end

        % Drop down opening function: LetterInfoDropDown
        function LetterInfoDropDownOpening(app, event)
            app.LetterInfoDropDownValueChanged(event);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.AButton = uibutton(app.UIFigure, 'push');
            app.AButton.ButtonPushedFcn = createCallbackFcn(app, @AButtonPushed, true);
            app.AButton.Text = 'A';
            app.BButton = uibutton(app.UIFigure, 'push');
            app.BButton.ButtonPushedFcn = createCallbackFcn(app, @BButtonPushed, true);
            app.BButton.Text = 'B';
            app.CButton = uibutton(app.UIFigure, 'push');
            app.CButton.ButtonPushedFcn = createCallbackFcn(app, @CButtonPushed, true);
            app.CButton.Text = 'C';
            app.DButton = uibutton(app.UIFigure, 'push');
            app.DButton.ButtonPushedFcn = createCallbackFcn(app, @DButtonPushed, true);
            app.DButton.Text = 'D';
            app.EButton = uibutton(app.UIFigure, 'push');
            app.EButton.ButtonPushedFcn = createCallbackFcn(app, @EButtonPushed, true);
            app.EButton.Text = 'E';
            app.FButton = uibutton(app.UIFigure, 'push');
            app.FButton.ButtonPushedFcn = createCallbackFcn(app, @FButtonPushed, true);
            app.FButton.Text = 'F';
            app.GButton = uibutton(app.UIFigure, 'push');
            app.GButton.ButtonPushedFcn = createCallbackFcn(app, @GButtonPushed, true);
            app.GButton.Text = 'G';
            app.HButton = uibutton(app.UIFigure, 'push');
            app.HButton.ButtonPushedFcn = createCallbackFcn(app, @HButtonPushed, true);
            app.HButton.Text = 'H';
            app.IButton = uibutton(app.UIFigure, 'push');
            app.IButton.ButtonPushedFcn = createCallbackFcn(app, @IButtonPushed, true);
            app.IButton.Text = 'I';
            app.JButton = uibutton(app.UIFigure, 'push');
            app.JButton.ButtonPushedFcn = createCallbackFcn(app, @JButtonPushed, true);
            app.JButton.Text = 'J';
            app.KButton = uibutton(app.UIFigure, 'push');
            app.KButton.ButtonPushedFcn = createCallbackFcn(app, @KButtonPushed, true);
            app.KButton.Text = 'K';
            app.LButton = uibutton(app.UIFigure, 'push');
            app.LButton.ButtonPushedFcn = createCallbackFcn(app, @LButtonPushed, true);
            app.LButton.Text = 'L';
            app.MButton = uibutton(app.UIFigure, 'push');
            app.MButton.ButtonPushedFcn = createCallbackFcn(app, @MButtonPushed, true);
            app.MButton.Text = 'M';
            app.NButton = uibutton(app.UIFigure, 'push');
            app.NButton.ButtonPushedFcn = createCallbackFcn(app, @NButtonPushed, true);
            app.NButton.Text = 'N';
            app.OButton = uibutton(app.UIFigure, 'push');
            app.OButton.ButtonPushedFcn = createCallbackFcn(app, @OButtonPushed, true);
            app.OButton.Text = 'O';
            app.PButton = uibutton(app.UIFigure, 'push');
            app.PButton.ButtonPushedFcn = createCallbackFcn(app, @PButtonPushed, true);
            app.PButton.Text = 'P';
            app.QButton = uibutton(app.UIFigure, 'push');
            app.QButton.ButtonPushedFcn = createCallbackFcn(app, @QButtonPushed, true);
            app.QButton.Text = 'Q';
            app.RButton = uibutton(app.UIFigure, 'push');
            app.RButton.ButtonPushedFcn = createCallbackFcn(app, @RButtonPushed, true);
            app.RButton.Text = 'R';
            app.SButton = uibutton(app.UIFigure, 'push');
            app.SButton.ButtonPushedFcn = createCallbackFcn(app, @SButtonPushed, true);
            app.SButton.Text = 'S';
            app.TButton = uibutton(app.UIFigure, 'push');
            app.TButton.ButtonPushedFcn = createCallbackFcn(app, @TButtonPushed, true);
            app.TButton.Text = 'T';
            app.UButton = uibutton(app.UIFigure, 'push');
            app.UButton.ButtonPushedFcn = createCallbackFcn(app, @UButtonPushed, true);
            app.UButton.Text = 'U';
            app.VButton = uibutton(app.UIFigure, 'push');
            app.VButton.ButtonPushedFcn = createCallbackFcn(app, @VButtonPushed, true);
            app.VButton.Text = 'V';
            app.WButton = uibutton(app.UIFigure, 'push');
            app.WButton.ButtonPushedFcn = createCallbackFcn(app, @WButtonPushed, true);
            app.WButton.Text = 'W';
            app.XButton = uibutton(app.UIFigure, 'push');
            app.XButton.ButtonPushedFcn = createCallbackFcn(app, @XButtonPushed, true);
            app.XButton.Text = 'X';
            app.YButton = uibutton(app.UIFigure, 'push');
            app.YButton.ButtonPushedFcn = createCallbackFcn(app, @YButtonPushed, true);
            app.YButton.Text = 'Y';
            app.ZButton = uibutton(app.UIFigure, 'push');
            app.ZButton.ButtonPushedFcn = createCallbackFcn(app, @ZButtonPushed, true);
            app.ZButton.Text = 'Z';
            app.PREVButton = uibutton(app.UIFigure, 'push');
            app.PREVButton.ButtonPushedFcn = createCallbackFcn(app, @PREVButtonPushed, true);
            app.PREVButton.Text = 'PREV';
            app.WordleAssiterLabel = uilabel(app.UIFigure);
            app.WordleAssiterLabel.Text = 'Wordle Assiter';
            app.RESETButton = uibutton(app.UIFigure, 'push');
            app.RESETButton.ButtonPushedFcn = createCallbackFcn(app, @RESETButtonPushed, true);
            app.RESETButton.Text = 'RESET';
            app.Panel = uipanel(app.UIFigure);
            app.GridLayout = uigridlayout(app.Panel);
            app.L1Label = uilabel(app.GridLayout);
            app.L1Label.Text = 'L1';
            app.L2Label = uilabel(app.GridLayout);
            app.L2Label.Text = 'L2';
            app.L3Label = uilabel(app.GridLayout);
            app.L3Label.Text = 'L3';
            app.L4Label = uilabel(app.GridLayout);
            app.L4Label.Text = 'L4';
            app.L5Label = uilabel(app.GridLayout);
            app.L5Label.Text = 'L5';
            app.L6Label = uilabel(app.GridLayout);
            app.L6Label.Text = 'L6';
            app.L7Label = uilabel(app.GridLayout);
            app.L7Label.Text = 'L7';
            app.L8Label = uilabel(app.GridLayout);
            app.L8Label.Text = 'L8';
            app.L9Label = uilabel(app.GridLayout);
            app.L9Label.Text = 'L9';
            app.L10Label = uilabel(app.GridLayout);
            app.L10Label.Text = 'L10';
            app.L11Label = uilabel(app.GridLayout);
            app.L11Label.Text = 'L11';
            app.L12Label = uilabel(app.GridLayout);
            app.L12Label.Text = 'L12';
            app.L13Label = uilabel(app.GridLayout);
            app.L13Label.Text = 'L13';
            app.L14Label = uilabel(app.GridLayout);
            app.L14Label.Text = 'L14';
            app.L15Label = uilabel(app.GridLayout);
            app.L15Label.Text = 'L15';
            app.L16Label = uilabel(app.GridLayout);
            app.L16Label.Text = 'L16';
            app.L17Label = uilabel(app.GridLayout);
            app.L17Label.Text = 'L17';
            app.L18Label = uilabel(app.GridLayout);
            app.L18Label.Text = 'L18';
            app.L19Label = uilabel(app.GridLayout);
            app.L19Label.Text = 'L19';
            app.L20Label = uilabel(app.GridLayout);
            app.L20Label.Text = 'L20';
            app.L21Label = uilabel(app.GridLayout);
            app.L21Label.Text = 'L21';
            app.L22Label = uilabel(app.GridLayout);
            app.L22Label.Text = 'L22';
            app.L23Label = uilabel(app.GridLayout);
            app.L23Label.Text = 'L23';
            app.L24Label = uilabel(app.GridLayout);
            app.L24Label.Text = 'L24';
            app.L25Label = uilabel(app.GridLayout);
            app.L25Label.Text = 'L25';
            app.L26Label = uilabel(app.GridLayout);
            app.L26Label.Text = 'L26';
            app.L27Label = uilabel(app.GridLayout);
            app.L27Label.Text = 'L27';
            app.L28Label = uilabel(app.GridLayout);
            app.L28Label.Text = 'L28';
            app.L29Label = uilabel(app.GridLayout);
            app.L29Label.Text = 'L29';
            app.L30Label = uilabel(app.GridLayout);
            app.L30Label.Text = 'L30';
            app.NEXTButton = uibutton(app.UIFigure, 'push');
            app.NEXTButton.ButtonPushedFcn = createCallbackFcn(app, @NEXTButtonPushed, true);
            app.NEXTButton.Text = 'NEXT';
            app.LetterInfoDropDownLabel = uilabel(app.UIFigure);
            app.LetterInfoDropDownLabel.Text = 'Letter Info';
            app.LetterInfoDropDown = uidropdown(app.UIFigure);
            app.LetterInfoDropDown.Items = {'N/A', 'Gray', 'Yellow', 'Green'};
            app.LetterInfoDropDown.DropDownOpeningFcn = createCallbackFcn(app, @LetterInfoDropDownOpening, true);
            app.LetterInfoDropDown.ValueChangedFcn = createCallbackFcn(app, @LetterInfoDropDownValueChanged, true);
            app.LetterInfoDropDown.Value = 'N/A';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Letter'; 'Probably'};
            app.UITable.RowName = {};
            app.UITable.SelectionType = 'row';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = WordleSolver

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end